classdef projection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TracerButton                  matlab.ui.control.Button
        AjouterButton                 matlab.ui.control.Button
        PlansconjugusdesstriesButton  matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        EffacerButton                 matlab.ui.control.Button
        AjouterPlanconjugusButton     matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            cla(app.UIAxes);
            cla(app.UIAxes2);
            %setup
            [A,B,C,D,E,F]=readvars('Stereo');
            
            x=readtable('Stereo');
            % Change column name from imported data
            app.UITable.ColumnName = x.Properties.VariableNames;
            %ajoute une ligne vide editable
            nr={'O',0,0,'O',0,'O'};
            app.UITable.Data =[nr;x];
            ncols = size(app.UITable.Data,2);
            app.UITable.ColumnEditable = true(1,ncols);
            
            
            erreur(A,B,C,D,E,F)
            theta = linspace(0,360,3600);
            y = 0*theta;
            x=cosd(theta);
            z=sind(theta);
            co= [x;y;z];
            
            %trace le stereographe
            hold(app.UIAxes,"on");
            hold(app.UIAxes2,"on");
            
            theta = linspace(0,360,1800);
            %Grand cercle :
            y = 0*theta;
            x=cosd(theta); z=sind(theta);
            plot3(app.UIAxes2,x,y,z, 'k-')
            for i=0:10:180
                alpha=i;
                coord= [x;y;z];
                rot_dip = [1,0,0;0, cosd(alpha),sind(alpha);0, -sind(alpha), cosd(alpha)];
                nc2 = rot_dip*coord;
                plot3(app.UIAxes2,nc2(1,:),nc2(2,:),nc2(3,:), 'b-');
                a=nc2(1,:); b=nc2(2,:);c=nc2(3,:);
                X = a(c<0)./(1-c(c<0)); Y=b(c<0)./(1-c(c<0));
                plot(app.UIAxes,Y,X,'g-')
            end
            
            %Petit cercle :
            w=ones(1,1800);
            for i=0:10:180
                x=cosd(i)*w;
                y=sind(i)*sind(theta);
                z=sind(i)*cosd(theta);
                plot3(app.UIAxes2,x,y,z, 'r-');
                
                coord= [x;y;z];
                rot_dip = [1,0,0;0, cosd(alpha+180),sind(alpha+180);0, -sind(alpha+180), cosd(alpha+180)];
                
                nc2 = rot_dip*coord;
                
                a=nc2(1,:); b=nc2(2,:);c=nc2(3,:);
                X = a(c<=0)./(1-c(c<=0)); Y=b(c<=0)./(1-c(c<=0));
                plot(app.UIAxes,Y,X,'c-')
            end
            %trace un cercle noir de référence
            p=pendage(90,co);
            fill3(app.UIAxes2,p(1,:),p(2,:),p(3,:),'k','FaceAlpha',0.5);
            x=p(1,:); y=p(2,:);z=p(3,:);
            X = x./(1-z); Y=y./(1-z);
            plot(app.UIAxes,Y,X,'k')
            set(app.UIAxes,'DataAspectRatio',[1 1 1])
            set(app.UIAxes2,'DataAspectRatio',[1 1 1])
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Button pushed function: TracerButton
        function TracerButtonPushed(app, event)
            app.PlansconjugusdesstriesButton.Enable="on";
            app.EffacerButton.Enable="on";
            
            [A,B,C,D,E,F]=readvars('Stereo');
            
            erreur(A,B,C,D,E,F)
            theta = linspace(0,360,3600);
            y = 0*theta;
            x=cosd(theta);
            z=sind(theta);
            co= [x;y;z];
            
            %trace le stereographe
            hold(app.UIAxes,"on");
            hold(app.UIAxes2,"on");
            
            
            %trace le plan
            azimuth=frtoit(B,D);
            for i = 1:length(C)
                p=pendage(90-C(i),co);
                a=f_azimuth(270-azimuth(i),p);
                fill3(app.UIAxes2,a(1,:),a(2,:),a(3,:),'g','FaceAlpha',0.5);
                
                %projection
                x=a(1,:); y=a(2,:);z=a(3,:);
                if z==0
                    X=x;
                    Y=y;
                else
                    X = x(z<0)./(1-z(z<0));
                    Y=y(z<0)./(1-z(z<0));
                end
                h=plot(app.UIAxes,Y,X,'b');
                h.MarkerSize=3; h.MarkerFaceColor = 'r';
                
                
                %trace pitch
                if ~isnan(E(i))
                    
                    if cellfun(@isempty,F(i))
                        coordpitch=chercheindice(X,Y,E(i));
                        X=X(coordpitch);Y=Y(coordpitch);
                        h=plot(app.UIAxes,Y,X,'ko');
                        h.MarkerSize=3; h.MarkerFaceColor = 'r';
                    else
                        pitch=werepitch(B(i),F(i),E(i));
                        coordpitch=tracepitch(pitch,C(i));
                        azipitch=f_azimuth(270-azimuth(i),coordpitch);
                        fill3(app.UIAxes2,azipitch(1,:),azipitch(2,:),azipitch(3,:),'g','FaceAlpha',0.5);
                        
                        %projection
                        x=azipitch(1,:); y=azipitch(2,:);z=azipitch(3,:);
                        if z==0
                            X=x;
                            Y=y;
                        else
                            X = x(z<0)./(1-z(z<0));
                            Y=y(z<0)./(1-z(z<0));
                        end
                        h=plot(app.UIAxes,Y,X,'ko');
                        h.MarkerSize=3; h.MarkerFaceColor = 'r';
                        
                    end
                    
                    % traceflèce
                    [a,b]=tracefleche(A(i),X,Y);
                    h=plot(app.UIAxes,a,b,'r');
                    h.LineWidth=1.5;
                end
                
            end
        end

        % Button pushed function: AjouterButton
        function AjouterButtonPushed(app, event)
            app.AjouterPlanconjugusButton.Enable="on";
            app.EffacerButton.Enable="on";
            
            Data=app.UITable.Data;
            A=table2cell(Data(1,1));
            B=table2array(Data(1,2));
            C=table2array(Data(1,3));
            D=table2cell(Data(1,4));
            E=table2array(Data(1,5));
            F=table2cell(Data(1,6));


            erreur(A,B,C,D,E,F)
            theta = linspace(0,360,3600);
            y = 0*theta;
            x=cosd(theta);
            z=sind(theta);
            co= [x;y;z];
            
            %trace le stereographe
            hold(app.UIAxes,"on");
            hold(app.UIAxes2,"on");
            
            
            %trace le plan
            azimuth=frtoit(B,D);
            for i = 1:length(C)
                p=pendage(90-C(i),co);
                a=f_azimuth(270-azimuth(i),p);
                fill3(app.UIAxes2,a(1,:),a(2,:),a(3,:),'g','FaceAlpha',0.5);
                
                %projection
                x=a(1,:); y=a(2,:);z=a(3,:);
                if z==0
                    X=x;
                    Y=y;
                else
                    X = x(z<0)./(1-z(z<0));
                    Y=y(z<0)./(1-z(z<0));
                end
                h=plot(app.UIAxes,Y,X,'b');
                h.MarkerSize=3; h.MarkerFaceColor = 'r';
                
                
                %trace pitch
                if ~isnan(E(i))
                    
                    if cellfun(@isempty,F(i))
                        coordpitch=chercheindice(X,Y,E(i));
                        X=X(coordpitch);Y=Y(coordpitch);
                        h=plot(app.UIAxes,Y,X,'ko');
                        h.MarkerSize=3; h.MarkerFaceColor = 'r';
                    else
                        pitch=werepitch(B(i),F(i),E(i));
                        coordpitch=tracepitch(pitch,C(i));
                        azipitch=f_azimuth(270-azimuth(i),coordpitch);
                        fill3(app.UIAxes2,azipitch(1,:),azipitch(2,:),azipitch(3,:),'g','FaceAlpha',0.5);
                        
                        %projection
                        x=azipitch(1,:); y=azipitch(2,:);z=azipitch(3,:);
                        if z==0
                            X=x;
                            Y=y;
                        else
                            X = x(z<0)./(1-z(z<0));
                            Y=y(z<0)./(1-z(z<0));
                        end
                        h=plot(app.UIAxes,Y,X,'ko');
                        h.MarkerSize=3; h.MarkerFaceColor = 'r';
                        
                    end
                    
                    % traceflèce
                    [a,b]=tracefleche(A(i),X,Y);
                    h=plot(app.UIAxes,a,b,'r');
                    h.LineWidth=1.5;
                end
                
            end
        end

        % Button pushed function: PlansconjugusdesstriesButton
        function PlansconjugusdesstriesButtonPushed(app, event)
            app.PlansconjugusdesstriesButton.Enable="off";
            
            [A,B,C,D,E,F]=readvars('Stereo');
            erreur(A,B,C,D,E,F)
            theta = linspace(0,360,3600);
            y = 0*theta;
            x=cosd(theta);
            z=sind(theta);
            co= [x;y;z];
            
            %trace le stereographe
            hold(app.UIAxes,"on");
            hold(app.UIAxes2,"on");
            
            
            %trace le plan
            azimuth=frtoit(B,D);
            for i = 1:length(C)
                p=pendage(90-C(i),co);
                a=f_azimuth(270-azimuth(i),p);
                fill3(app.UIAxes2,a(1,:),a(2,:),a(3,:),'g','FaceAlpha',0.5);
                
                %projection
                x=a(1,:); y=a(2,:);z=a(3,:);
                if z==0
                    X=x;
                    Y=y;
                else
                    X = x(z<0)./(1-z(z<0));
                    Y=y(z<0)./(1-z(z<0));
                end
                h=plot(app.UIAxes,Y,X,'b');
                h.MarkerSize=3; h.MarkerFaceColor = 'r';
                
                
                %trace pitch
                if ~isnan(E(i))
                    
                    if cellfun(@isempty,F(i))
                        coordpitch=chercheindice(X,Y,E(i));
                        X=X(coordpitch);Y=Y(coordpitch);
                        h=plot(app.UIAxes,Y,X,'ko');
                        h.MarkerSize=3; h.MarkerFaceColor = 'r';
                        
                    else
                        pitch=werepitch(B(i),F(i),E(i));
                        coordpitch=tracepitch(pitch,C(i));
                        azipitch=f_azimuth(270-azimuth(i),coordpitch);
                        fill3(app.UIAxes2,azipitch(1,:),azipitch(2,:),azipitch(3,:),'g','FaceAlpha',0.5);
                        
                        %projection
                        x=azipitch(1,:); y=azipitch(2,:);z=azipitch(3,:);
                        if z==0
                            X=x;
                            Y=y;
                        else
                            X = x(z<0)./(1-z(z<0));
                            Y=y(z<0)./(1-z(z<0));
                        end
                        h=plot(app.UIAxes,Y,X,'ko');
                        h.MarkerSize=3; h.MarkerFaceColor = 'r';
                        
                    end
                    
                    % traceflèce
                    [af,bf]=tracefleche(A(i),X,Y);
                    h=plot(app.UIAxes,af,bf,'r');
                    h.LineWidth=1.5;
                    
                    
                    if cellfun(@isempty,F(i))
                        
                        % trace plan conjugué de la strie
                        
                        theta = linspace(0,360,360000);
                        y = 0*theta;
                        x=cosd(theta);
                        z=sind(theta);
                        coord= [x;y;z];
                        
                        xp=X; yp=Y;
                        x=coord(1,:); z=coord(3,:);
                        
                        xt=  xp;
                        yt=  yp;
                        
                        for j=1:360000
                            if yt/x(j)<1.0001 && yt/x(j)>0.9998
                                Z=z(j);
                            end
                        end
                        
                        k=1;
                        if X>0 
                            k=-1;
                        end
                        
                        alpha1=acosd(abs(yt/Z)*k);
                        alpha2=90-alpha1;
                        
                        azimuth2=frtoit(90,0);
                        
                        % pitch pour ordonnée = 0
                        p1=pendage(90-alpha1,coord);
                        a1=f_azimuth(270-azimuth2,p1);
                        zr=a1(3,:);
                        
                        if zr==0
                            Xt=xt;
                            Yt=yt;
                        else
                            Yt = xt/(1-zt);
                            Xt=yt/(1-zt);
                        end
                        
                        alphaz=atand(Xt/Yt);
                        M_rot=[cosd(alphaz),-sind(alphaz);sind(alphaz),cosd(alphaz)];
                        
                        % Plan complémentaire
                        
                        p2=pendage(90-alpha2,coord);
                        a2=f_azimuth(270-azimuth2,p2);
                        xc=a2(1,:); yc=a2(2,:);zc=a2(3,:);
                        if zc==0
                            Xt=xc;
                            Yt=yc;
                        else
                            Yt = xc(zc<0)./(1-zc(zc<0));
                            Xt=yc(zc<0)./(1-zc(zc<0));
                        end
                        
                        T=[Xt;Yt];
                        Tz=M_rot*T;
                        At=Tz(1,:); Bt=Tz(2,:);
                        
                        plot(app.UIAxes,Bt,At,'m')
                        
                        h=plot(app.UIAxes,Bt(round(length(Bt)/2)),At(round(length(At)/2)),'ko');
                        h.MarkerSize=3; h.MarkerFaceColor = 'm';
                        
                    else
                        
                        % trace plan conjugué de la strie
                        
                        theta = linspace(0,360,360000);
                        y = 0*theta;
                        x=cosd(theta);
                        z=sind(theta);
                        coord= [x;y;z];
                        
                        xp=azipitch(1,:); yp=azipitch(2,:);zp=azipitch(3,:);
                        x=coord(1,:); z=coord(3,:);
                        
                        xt=  xp(zp<0);
                        yt=  yp(zp<0);
                        zt = zp(zp<0);
                        
                        for j=1:360000
                            if yt/x(j)<1.0001 && yt/x(j)>0.9998
                                Z=z(j);
                            end
                        end
                        
                        k=1;
                        if X>0 
                            k=-1;
                        end
                        
                        alpha1=asind(abs(zt/Z)*k);
                        alpha2=90-alpha1;
                        
                        azimuth2=frtoit(90,0);
                        
                        % pitch pour ordonnée = 0
                        p1=pendage(90-alpha1,coord);
                        a1=f_azimuth(270-azimuth2,p1);
                        zr=a1(3,:);
                        
                        if zr==0
                            Xt=xt;
                            Yt=yt;
                        else
                            Yt = xt/(1-zt);
                            Xt=yt/(1-zt);
                        end
                        
                        alphaz=atand(Xt/Yt);
                        M_rot=[cosd(alphaz),-sind(alphaz);sind(alphaz),cosd(alphaz)];
                        
                        % Plan complémentaire
                        
                        p2=pendage(90-alpha2,coord);
                        a2=f_azimuth(270-azimuth2,p2);
                        xc=a2(1,:); yc=a2(2,:);zc=a2(3,:);
                        if zc==0
                            Xt=xc;
                            Yt=yc;
                        else
                            Yt = xc(zc<0)./(1-zc(zc<0));
                            Xt=yc(zc<0)./(1-zc(zc<0));
                        end
                        
                        T=[Xt;Yt];
                        Tz=M_rot*T;
                        At=Tz(1,:); Bt=Tz(2,:);
                        
                        plot(app.UIAxes,Bt,At,'m')
                        
                        h=plot(app.UIAxes,Bt(round(length(Bt)/2)),At(round(length(At)/2)),'ko');
                        h.MarkerSize=3; h.MarkerFaceColor = 'm';
                        
                    end
                end
            end
        end

        % Button pushed function: EffacerButton
        function EffacerButtonPushed(app, event)
            app.TracerButton.Enable="on";
            app.PlansconjugusdesstriesButton.Enable="off";
            app.AjouterPlanconjugusButton.Enable="off";
            app.AjouterButton.Enable="on";
            
            cla(app.UIAxes);
            cla(app.UIAxes2);
            %setup
            [A,B,C,D,E,F]=readvars('Stereo');
            erreur(A,B,C,D,E,F)
            theta = linspace(0,360,3600);
            y = 0*theta;
            x=cosd(theta);
            z=sind(theta);
            co= [x;y;z];
            
            %trace le stereographe
            hold(app.UIAxes,"on");
            hold(app.UIAxes2,"on");
            
            theta = linspace(0,360,1800);
            %Grand cercle :
            y = 0*theta;
            x=cosd(theta); z=sind(theta);
            plot3(app.UIAxes2,x,y,z, 'k-')
            for i=0:10:180
                alpha=i;
                coord= [x;y;z];
                rot_dip = [1,0,0;0, cosd(alpha),sind(alpha);0, -sind(alpha), cosd(alpha)];
                nc2 = rot_dip*coord;
                plot3(app.UIAxes2,nc2(1,:),nc2(2,:),nc2(3,:), 'b-');
                a=nc2(1,:); b=nc2(2,:);c=nc2(3,:);
                X = a(c<0)./(1-c(c<0)); Y=b(c<0)./(1-c(c<0));
                plot(app.UIAxes,Y,X,'g-')
            end
            
            %Petit cercle :
            w=ones(1,1800);
            for i=0:10:180
                x=cosd(i)*w;
                y=sind(i)*sind(theta);
                z=sind(i)*cosd(theta);
                plot3(app.UIAxes2,x,y,z, 'r-');
                
                coord= [x;y;z];
                rot_dip = [1,0,0;0, cosd(alpha+180),sind(alpha+180);0, -sind(alpha+180), cosd(alpha+180)];
                
                nc2 = rot_dip*coord;
                
                a=nc2(1,:); b=nc2(2,:);c=nc2(3,:);
                X = a(c<=0)./(1-c(c<=0)); Y=b(c<=0)./(1-c(c<=0));
                plot(app.UIAxes,Y,X,'c-')
            end
            %trace un cercle noir de référence
            p=pendage(90,co);
            fill3(app.UIAxes2,p(1,:),p(2,:),p(3,:),'k','FaceAlpha',0.5);
            x=p(1,:); y=p(2,:);z=p(3,:);
            X = x./(1-z); Y=y./(1-z);
            plot(app.UIAxes,Y,X,'k')
            set(app.UIAxes,'DataAspectRatio',[1 1 1])
            set(app.UIAxes2,'DataAspectRatio',[1 1 1])
        end

        % Button pushed function: AjouterPlanconjugusButton
        function AjouterPlanconjugusButtonPushed(app, event)
            %setup
            
            app.AjouterPlanconjugusButton.Enable="off";
            
            Data=app.UITable.Data;
            A=table2cell(Data(1,1));
            B=table2array(Data(1,2));
            C=table2array(Data(1,3));
            D=table2cell(Data(1,4));
            E=table2array(Data(1,5));
            F=table2cell(Data(1,6));
            
            
            erreur(A,B,C,D,E,F)
            theta = linspace(0,360,3600);
            y = 0*theta;
            x=cosd(theta);
            z=sind(theta);
            co= [x;y;z];
            
            %trace le stereographe
            hold(app.UIAxes,"on");
            hold(app.UIAxes2,"on");
            
            
            %trace le plan
            azimuth=frtoit(B,D);
            for i = 1:length(C)
                p=pendage(90-C(i),co);
                a=f_azimuth(270-azimuth(i),p);
                fill3(app.UIAxes2,a(1,:),a(2,:),a(3,:),'g','FaceAlpha',0.5);
                
                %projection
                x=a(1,:); y=a(2,:);z=a(3,:);
                if z==0
                    X=x;
                    Y=y;
                else
                    X = x(z<0)./(1-z(z<0));
                    Y=y(z<0)./(1-z(z<0));
                end
                h=plot(app.UIAxes,Y,X,'b');
                h.MarkerSize=3; h.MarkerFaceColor = 'r';
                
                
                %trace pitch
                if ~isnan(E(i))
                    
                    if cellfun(@isempty,F(i))
                        coordpitch=chercheindice(X,Y,E(i));
                        X=X(coordpitch);Y=Y(coordpitch);
                        h=plot(app.UIAxes,Y,X,'ko');
                        h.MarkerSize=3; h.MarkerFaceColor = 'r';
                        
                        
                        % traceflèce
                        [af,bf]=tracefleche(A(i),X,Y);
                        h=plot(app.UIAxes,af,bf,'r');
                        h.LineWidth=1.5;
                        
                        
                        % trace plan conjugué de la strie
                        
                        theta = linspace(0,360,360000);
                        y = 0*theta;
                        x=cosd(theta);
                        z=sind(theta);
                        coord= [x;y;z];
                        
                        xp=X; yp=Y;
                        x=coord(1,:); z=coord(3,:);
                        
                        xt=  xp;
                        yt=  yp;
                        
                        for j=1:360000
                            if yt/x(j)<1.0001 && yt/x(j)>0.9998
                                Z=z(j);
                            end
                        end
                        k=1;
                        if X>0
                            k=-1;
                        end
                        
                        alpha1=acosd(abs(xt/Z)*k);
                        alpha2=90-alpha1;
                        zt=sind(alpha1)*Z;
                        
                        azimuth2=frtoit(90,0);
                        
                        % pitch pour ordonnée = 0
                        p1=pendage(90-alpha1,coord);
                        a1=f_azimuth(270-azimuth2,p1);
                        zr=a1(3,:);
                        
                        if zr==0
                            Xt=xt;
                            Yt=yt;
                        else
                            Yt = xt/(1-zt);
                            Xt=yt/(1-zt);
                        end
                        
                        alphaz=atand(Xt/Yt);
                        M_rot=[cosd(alphaz),-sind(alphaz);sind(alphaz),cosd(alphaz)];
                        
                        % Plan complémentaire
                        
                        p2=pendage(90-alpha2,coord);
                        a2=f_azimuth(270-azimuth2,p2);
                        xc=a2(1,:); yc=a2(2,:);zc=a2(3,:);
                        if zc==0
                            Xt=xc;
                            Yt=yc;
                        else
                            Yt = xc(zc<0)./(1-zc(zc<0));
                            Xt=yc(zc<0)./(1-zc(zc<0));
                        end
                        
                        T=[Xt;Yt];
                        Tz=M_rot*T;
                        At=Tz(1,:); Bt=Tz(2,:);
                        
                        plot(app.UIAxes,Bt,At,'m')
                        
                        h=plot(app.UIAxes,Bt(round(length(Bt)/2)),At(round(length(At)/2)),'ko');
                        h.MarkerSize=3; h.MarkerFaceColor = 'm';
                        
                    else
                        pitch=werepitch(B(i),F(i),E(i));
                        coordpitch=tracepitch(pitch,C(i));
                        azipitch=f_azimuth(270-azimuth(i),coordpitch);
                        fill3(app.UIAxes2,azipitch(1,:),azipitch(2,:),azipitch(3,:),'g','FaceAlpha',0.5);
                        
                        %projection
                        x=azipitch(1,:); y=azipitch(2,:);z=azipitch(3,:);
                        if z==0
                            X=x;
                            Y=y;
                        else
                            X = x(z<0)./(1-z(z<0));
                            Y=y(z<0)./(1-z(z<0));
                        end
                        h=plot(app.UIAxes,Y,X,'ko');
                        h.MarkerSize=3; h.MarkerFaceColor = 'r';
                        
                        
                        % traceflèce
                        [af,bf]=tracefleche(A(i),X,Y);
                        h=plot(app.UIAxes,af,bf,'r');
                        h.LineWidth=1.5;
                        
                        
                        % trace plan conjugué de la strie
                        
                        theta = linspace(0,360,360000);
                        y = 0*theta;
                        x=cosd(theta);
                        z=sind(theta);
                        coord= [x;y;z];
                        
                        xp=azipitch(1,:); yp=azipitch(2,:);zp=azipitch(3,:);
                        x=coord(1,:); z=coord(3,:);
                        
                        xt=  xp(zp<0);
                        yt=  yp(zp<0);
                        zt = zp(zp<0);
                        
                        for j=1:360000
                            if yt/x(j)<1.0001 && yt/x(j)>0.9998
                                Z=z(j);
                            end
                        end
                        k=1;
                        if X>0 
                            k=-1;
                        end


                        alpha1=asind(abs(zt/Z)*k);
                        alpha2=90-alpha1;
                        
                        azimuth2=frtoit(90,0);
                        
                        % pitch pour ordonnée = 0
                        p1=pendage(90-alpha1,coord);
                        a1=f_azimuth(270-azimuth2,p1);
                        zr=a1(3,:);
                        
                        if zr==0
                            Xt=xt;
                            Yt=yt;
                        else
                            Yt = xt/(1-zt);
                            Xt=yt/(1-zt);
                        end
                        
                        alphaz=atand(Xt/Yt);
                        M_rot=[cosd(alphaz),-sind(alphaz);sind(alphaz),cosd(alphaz)];
                        
                        % Plan complémentaire
                        
                        p2=pendage(90-alpha2,coord);
                        a2=f_azimuth(270-azimuth2,p2);
                        xc=a2(1,:); yc=a2(2,:);zc=a2(3,:);
                        if zc==0
                            Xt=xc;
                            Yt=yc;
                        else
                            Yt = xc(zc<0)./(1-zc(zc<0));
                            Xt=yc(zc<0)./(1-zc(zc<0));
                        end
                        
                        T=[Xt;Yt];
                        Tz=M_rot*T;
                        At=Tz(1,:); Bt=Tz(2,:);
                        
                        plot(app.UIAxes,Bt,At,'m')
                        
                        h=plot(app.UIAxes,Bt(round(length(Bt)/2)),At(round(length(At)/2)),'ko');
                        h.MarkerSize=3; h.MarkerFaceColor = 'm';
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TracerButton = uibutton(app.UIFigure, 'push');
            app.TracerButton.ButtonPushedFcn = createCallbackFcn(app, @TracerButtonPushed, true);
            app.TracerButton.Text = 'Tracer';
            app.AjouterButton = uibutton(app.UIFigure, 'push');
            app.AjouterButton.ButtonPushedFcn = createCallbackFcn(app, @AjouterButtonPushed, true);
            app.AjouterButton.Text = 'Ajouter';
            app.PlansconjugusdesstriesButton = uibutton(app.UIFigure, 'push');
            app.PlansconjugusdesstriesButton.ButtonPushedFcn = createCallbackFcn(app, @PlansconjugusdesstriesButtonPushed, true);
            app.PlansconjugusdesstriesButton.Enable = 'off';
            app.PlansconjugusdesstriesButton.Text = 'Plans conjugués des stries';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Type de Mesure'; 'Azimuth'; 'Pendage'; 'Orientation'; 'Pitch'; 'Orientation pitch'};
            app.UITable.RowName = {};
            app.EffacerButton = uibutton(app.UIFigure, 'push');
            app.EffacerButton.ButtonPushedFcn = createCallbackFcn(app, @EffacerButtonPushed, true);
            app.EffacerButton.Enable = 'off';
            app.EffacerButton.Text = 'Effacer';
            app.AjouterPlanconjugusButton = uibutton(app.UIFigure, 'push');
            app.AjouterPlanconjugusButton.ButtonPushedFcn = createCallbackFcn(app, @AjouterPlanconjugusButtonPushed, true);
            app.AjouterPlanconjugusButton.Enable = 'off';
            app.AjouterPlanconjugusButton.Text = 'Ajouter Plan conjugués';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Projection Stéreographique 2 D')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Projection Stéreographique 3 D')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end